classdef Auraliza < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        MainTab                         matlab.ui.container.Tab
        ViewType                        matlab.ui.control.Switch
        CAMHeadTrackerButton            matlab.ui.control.Button
        PauseButton                     matlab.ui.control.StateButton
        PlayButton                      matlab.ui.control.StateButton
        BinauralRenderButton            matlab.ui.control.StateButton
        AirabsorptionCheckBox           matlab.ui.control.CheckBox
        DistanceSlider                  matlab.ui.control.Slider
        DIstancemLabel                  matlab.ui.control.Label
        AditionalparametersPanel        matlab.ui.container.Panel
        InterpolationDropDown           matlab.ui.control.DropDown
        InterpolationDropDownLabel      matlab.ui.control.Label
        BufferSize                      matlab.ui.control.NumericEditField
        BufferSizeLabel                 matlab.ui.control.Label
        InputsPanel                     matlab.ui.container.Panel
        AudioWAVButton                  matlab.ui.control.StateButton
        HRTFSOFAButton                  matlab.ui.control.StateButton
        AUDIOpath                       matlab.ui.control.EditField
        SOFApath                        matlab.ui.control.EditField
        UIAxes                          matlab.ui.control.UIAxes
        AdvancedTab                     matlab.ui.container.Tab
        HeadphoneequalizationPanel      matlab.ui.container.Panel
        LoadfromDatabaseButton          matlab.ui.control.StateButton
        XButton                         matlab.ui.control.Button
        LoadHpIRwavButton               matlab.ui.control.StateButton
        opt_IRpath                      matlab.ui.control.EditField
        UIAxes2                         matlab.ui.control.UIAxes
        DistancevariationmethodListBox  matlab.ui.control.ListBox
        DistancevariationmethodListBoxLabel  matlab.ui.control.Label
        AirabsorptionpropertiesISO96131Panel  matlab.ui.container.Panel
        airPressure                     matlab.ui.control.NumericEditField
        AtmosphericpressureratioCurrentStandadEditFieldLabel  matlab.ui.control.Label
        airHumidity                     matlab.ui.control.NumericEditField
        HumidityLabel                   matlab.ui.control.Label
        airTemperature                  matlab.ui.control.NumericEditField
        TemperatureCEditFieldLabel      matlab.ui.control.Label
        HelpTab                         matlab.ui.container.Tab
        ContactLabel                    matlab.ui.control.Label
        davicarvalhoeacufsmbrLabel      matlab.ui.control.Label
        DaviRochaCarvalhoEngAcsticaUFSMLabel  matlab.ui.control.Label
    end

    properties (Access = public)
        Pax           % Polar axes
        Obj_HRTF      % SOFA HRTF file
        posi          % posições de fonte 
        fs_SOFA       % sample rate SOFA
        fs_Audio      % sample rate Audio 
        Audio         % Audio file input
        deviceWriter  % dispositivo de reprodução
        dspHRIR_L     % HRIR esquerda para a posição selecionada
        dspHRIR_R     % HRIR esquerda para a posição selecionada
        NameAudio     % nome no Audio sem extensão
        Audiofolder   % folder path 
        opt_IR        % input IR opcional
        fs_opt_IR     % sample rate da IR opcional
        no_input      % numero de canais de entrada 
        flag_opt_IR= 0;   % avisa se ha input
        ROI           % região de interesse (posição da fonte)
        ROI_flag=1;     % avisa se ROI foi inicializado ou não
        roi_listen    % roi listener 
        convAudio     % audio convoluido com resposta impulsiva
        flag_interp='';   % flag de erro de interpolação
        ROI_list      % lista com nome dos ROI
        headradius    % Raio da cabeça do sujeito 
        samplingGrid  % Posições SOFA originais
        DistNorm     % Amplitude normalization for distance variation
        startUP = 1;      % flag pra carregar arquivos default
        DistValue     % valor da distancia atribuida pelo slider
        FIR           % Alocated FIR filters 
        idx_FIR       % Index do filtro correspondente a posição atual 
        bkp_FIR       % save FIR when not in use, avoid ifs in the main loop
        MarkerSize    % Tamanho da bolinha do ROI
        air_abs       % coeficiente de absorção do ar
        N             % 1/2 do número de samples da HRIR
        flag_DistChanged   % flag pra indicar se distancia foi variada ou não
        flag_HeadTracker = 0;  % flag pra indicar se HT está ligado 
        udpr           % UDP connection to the head tracker
        props         % plot properties
        flag_airabs_eval  % indica se calculo da absorcao precisa ser atualizado
        parallax_pos_L  % parallaxed new grid (left)
        parallax_pos_R  % parallaxed new grid (right)
        conv_done= false; %flag para indicar se HpTF ja foi aplicada ou nao 
        norm_freq       % normalizd frequency vector 
        AirAbsFilter    % air absorption filter
        abs_dist_vec    % pre calculated distances for the air absorption 
        dist_FIR        % FIR filter for distance variation via HRTF equalization
        radius          % Source position radius for each source radius.(name)
        HRTFs           % Same as Obj.Data.IR, but with shifted dimensions
        elevation       % ROI's elevation info (avoid losing when transitioning views)
       
    end
    
        
    methods (Access = private)  
        function HRIR_filter_design(app, azim, elev, ch)
            %%% Por interpolação
            interp_method = app.InterpolationDropDown.Value;
            if any(strcmp(interp_method, {'VBAP', 'Bilinear'}))      
            % %               [azim,elev] = nav2sph(-azim,elev);
            %     idx_pos = dsearchn(app.posi(:,1:2), [azim,elev]);
            %     % num da pra usar parallax com interpolacao da forma como ta implementado aqui (acho)            
            %     posL = dsearchn(app.samplingGrid(:,1:2), app.parallax_pos_L(idx_pos,:));
            %     posR = dsearchn(app.samplingGrid(:,1:2), app.parallax_pos_R(idx_pos,:));
            %     % miinterolateHRTF possui pequenas correcoes na função original (valido para <= rR2020a)
            %     interpolatedIR_L = miinterpolateHRTF(shiftdim(app.Obj_HRTF.Data.IR,1),...
            %                                      app.posi(:,[1,2]),app.parallax_pos_L(idx_pos),...
            %                                      'Algorithm', app.InterpolationDropDown.Value); 
            %     interpolatedIR_R = miinterpolateHRTF(shiftdim(app.Obj_HRTF.Data.IR,1),...
            %                                      app.posi(:,[1,2]),app.parallax_pos_R(idx_pos),...
            %                                      'Algorithm', app.InterpolationDropDown.Value);
            %     idx_pos = length(app.posi) + 1;
            %     app.idx_FIR.(ch) = [idx_pos, idx_pos];
            %     app.dspHRIR_L.(ch) = squeeze(interpolatedIR(:,1,:)).';
            %     app.dspHRIR_R.(ch) = squeeze(interpolatedIR(:,2,:)).'; 
            %     % Caso erro na interpolação use nearest 
            %     if any(isnan(interpolatedIR(:)))
            %         app.flag_interp = 'Empty';                        
            %     end
                
                % get the parallax coordinate coorection
                [posiL, posiR] = parallax_interp(app, azim, elev);
            
                % do interpolation
                interH_L = squeeze(interpolateHRTF(app.Obj_HRTF.Data.IR, app.posi(:,1:2),...
                                                   posiL,...
                                                   'Algorithm', interp_method));
                Hl = interH_L(1,:).';
                if posiL ~= posiR
                    interH_R = squeeze(interpolateHRTF(app.Obj_HRTF.Data.IR, app.posi(:,1:2),...
                                                       posiR,...
                                                       'Algorithm', interp_method));    
                    Hr = interH_R(1,:).';
                else
                    Hr = interH_L(2,:).';
                end
                
                % Set index positions
                app.posi = [app.posi(:,1:2); 
                                   posiL;
                                   posiR];
                posL = length(app.posi)-1; 
                posR = length(app.posi);
                app.idx_FIR.(ch) = [posL, posR];
                app.dspHRIR_L(posL,:) = Hl;
                app.dspHRIR_R(posR,:) = Hr;
            end
            
            %%% Pela posição mais próxima
            if  any(strcmp(interp_method, 'Nearest')) ||...
                                  any(strcmp(app.flag_interp, 'Empty')) 
                idx_pos = dsearchn(app.posi(:,1:2), [azim,elev]); % azim/elev here are taken in navigational coordinates
                posL = dsearchn(app.samplingGrid(:,1:2), app.parallax_pos_L(idx_pos,:));
                posR = dsearchn(app.samplingGrid(:,1:2), app.parallax_pos_R(idx_pos,:));
            
                app.idx_FIR.(ch) = [posL, posR];
                
                Hl = app.HRTFs(:,posL,1);
                Hr = app.HRTFs(:,posR,2);
                if strcmp(app.InterpolationDropDown.Value, {'VBAP', 'Bilinear'})
                    % Reseta flag em interpolação caso tenha dado errado
                    app.flag_interp = ''; 
                end
            end


            %%%% Atenuacao com a distancia --------------------------------------------
            if app.flag_DistChanged || app.ROI_flag
                get_DistNorm(app, azim, elev, ch)                    
            end
            %%%% Absorcao do ar ------------------------------------------------------
            % caso queira absorcao do ar e num tenha HT ativado
            if ~app.flag_HeadTracker && app.AirabsorptionCheckBox.Value && ...
                app.DistValue > 2 % only apply for distances larger than 2m
                   apply_air_abs(app, Hl, Hr, posL, posR);                                         
            end
            end       


            function apply_air_abs(app, Hl, Hr, idx_L, idx_R)                       
                idx_air_abs = dsearchn(app.abs_dist_vec', app.DistValue);
                air_filter = app.AirAbsFilter{idx_air_abs};
                app.dspHRIR_L(idx_L,:) = air_filter(Hl);
                app.dspHRIR_R(idx_R,:) = air_filter(Hr);
            end    


            function get_DistNorm(app, azim, elev, name)
                msrd_dist = app.Obj_HRTF.SourcePosition(1,3);
                method = app.DistancevariationmethodListBox.Value;
                
                source_radius = app.radius.(name);
            
                
                if source_radius <= app.headradius
                    source_radius = app.headradius+0.12;
                end
                
                source_nb = sscanf(name,'CH%d');
                if strcmpi(method, 'Inverse Law') 
                    L_ear = app.Obj_HRTF.ReceiverPosition(1,:);
                    R_ear = app.Obj_HRTF.ReceiverPosition(2,:);
                    [tx, ty, tz] = sph2cart(azim, elev, source_radius);       
                    Ldist = sqrt((L_ear(1) - tx)^2 + (L_ear(2) - ty)^2 + (L_ear(3) - tz)^2);
                    Rdist = sqrt((R_ear(1) - tx)^2 + (R_ear(2) - ty)^2 + (R_ear(3) - tz)^2);
                    % Calculate distance normalization                   
                    app.DistNorm(source_nb,:) = [msrd_dist/Ldist, msrd_dist/Rdist];     
                else
                    sg = [azim, elev, msrd_dist;
                          azim, elev, source_radius];
                    ear=[90 0];
            
                    h = AKsphericalHead(sg, ear, false, app.headradius,...
                                        sg(1,3), 40, 512, app.fs_SOFA, 342);
                    %Get distance variation functions for left and right ear
                    h_freq = fft(h);
                    H_eq = squeeze(ifft(h_freq(:,2,:)./h_freq(:,1,:))).';
                    
                    % aplicar sobre hrtfs
                    app.dist_FIR.(name) = {dsp.FIRFilter('Numerator', H_eq(1,:)),...
                                           dsp.FIRFilter('Numerator', H_eq(2,:))};
                end
                markersize(app);
            end     
            
            
%             function markersize(app)
%                 app.MarkerSize = app.headradius/(app.DistValue)*100;
%                 if app.MarkerSize < 7               
%                     app.MarkerSize = 7;
%                 end
%             end
            
            
            function [posiL, posiR] = parallax_interp(app, azim, elev)         
                if app.DistValue < app.Obj_HRTF.SourcePosition(1,3)
                    [posiL, posiR] = hrtf_parallax([azim, elev],...
                                                    app.DistValue,...
                                                    app.headradius); 
                else
                    posiL = [azim, elev];
                    posiR = [azim, elev];
                end
            end 
        
        
        function initializeROI(app, azimm, elevv)
        if nargin < 3 || isempty(azimm)
             [azimm, elevv] = n_sources_coord(app.no_input);
             app.elevation = elevv;
        end
        app.ROI_list = cell(app.no_input,1);
        for k = 1:app.no_input                      
            roi_name = (['CH' num2str(k)]);
            app.ROI_list{k} = roi_name;  
            
            %%% Desenhar ROI objeto %%%
            if app.ROI_flag % evita reinicializar o ROI e outras coisas a cada play               
                get_parallax_pos(app)  % inicializar parallax
                
                % (ROI coordinates change according to view)
                switch app.ViewType.Value
                    case 'Frontal'  % (ROI : azimuth / elevation)
                        y_coord = elevv(k);
                        x_coord = azimm(k);
                        app.radius.(roi_name) = app.DistValue; % case change the view later
                        markersize(app);
                    case 'Top' % (ROI : azimuth / radius)
                        try 
                        	 y_coord = app.radius.(roi_name);
                        catch
                             y_coord = app.DistValue;
                             app.radius.(roi_name) = y_coord;
                        end
                        [x_coord, y_coord,~] = sph2cart(deg2rad(azimm(k)),...
                                                  0,...
                                                  y_coord);
                        app.MarkerSize = 7;
                end
                
                % actually draw the ROI
                if verLessThan('matlab','9.9') % markersize(num existia antes do R2020b)
                    app.ROI.(roi_name) = drawpoint(app.UIAxes, 'Position',[x_coord, y_coord],...
                                           'color', 'y');
                else
                    app.ROI.(roi_name) = drawpoint(app.UIAxes, 'Position',[x_coord, y_coord],...
                                           'color', 'y', 'MarkerSize', app.MarkerSize);
                end
                
                % initialize hrtf variables and values
                app.ROI.(roi_name).Tag = roi_name; % Valor interno 
                HRIR_filter_design(app, azimm(k), elevv(k), roi_name); % view independent  
                app.roi_listen = addlistener(app.ROI.(roi_name), 'MovingROI', @app.allevents);
                app.ROI.(roi_name).Label = roi_name; % Valor visível                            
            end
        end
        set(app.UIFigure,'doublebuffer','off');
        app.ROI_flag = 0;   % Indica que já foi desenhado             
        end
            
        function allevents(app, src, ~)
            pos = src.Position;
            switch app.ViewType.Value 
                case 'Top'
                    pos = src.Position;
                    [azimuth,~,radi]=cart2sph(pos(1), pos(2), 0);   
                    azimuth = rad2deg(azimuth);
                    elevation = 0;              
                    app.radius.(src.Tag) = radi;
                    app.DistValue = radi;
                    app.flag_DistChanged = 1;                 
                otherwise                    
                    azimuth = pos(1);
                    elevation = pos(2);
            end
            HRIR_filter_design(app, azimuth, elevation, src.Tag)
        end
        
        function get_parallax_pos(app)         
            if app.DistValue < app.posi(1,3)
                [app.parallax_pos_L, app.parallax_pos_R]=...
                                    hrtf_parallax(app.samplingGrid,...
                                                  app.DistValue,...
                                                  app.headradius); 
            else
                app.parallax_pos_L = app.samplingGrid;
                app.parallax_pos_R = app.samplingGrid;
            end
        end 
        
        function markersize(app)
                app.MarkerSize = app.headradius/(app.DistValue)*100;
            if  app.MarkerSize < 7               
                app.MarkerSize = 7;
            end
        end        
        
        %% DRAW FRONT VIEW %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        function draw_front_view(app, azimm, elevv)
            if nargin < 2
                azimm = [];
                elevv = [];
            end
            hold(app.UIAxes, 'off');  cla(app.UIAxes)
            axis(app.UIAxes,'normal')
            x=app.posi(:,1); y=app.posi(:,2);
            line(app.UIAxes,[x,x],[y,y],app.props{:}); 
            hold(app.UIAxes, 'on');  
            xlim(app.UIAxes, [-180, 180])
            ylim(app.UIAxes, [-90, 90])
            view(app.UIAxes, [0 90])
            set(app.UIAxes,'XColor', [.4 .4 .4],'YColor', [.4 .4 .4])
            %> Initialize ROI
            initializeROI(app, azimm, elevv)          
        end
        
        %% DRAW TOP VIEW %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        function draw_top_view(app, azimm, elevv)
            if nargin < 2
                azimm = [];
                elevv = [];
            end
            hold(app.UIAxes, 'off');  cla(app.UIAxes);
            hold(app.UIAxes, 'on');
            r = [app.headradius, 0.50, 1, 1.5, 2, 3, 4];
            circles(app,r);          
            axis(app.UIAxes, 'tight')
            xlim(app.UIAxes, [-r(end), r(end)])
            ylim(app.UIAxes, [-r(end), r(end)])
            axis(app.UIAxes, 'square') 
            view(app.UIAxes, [90 -90])
            set(app.UIAxes,'XColor', 'none','YColor','none')

            %> Initialize ROI
            initializeROI(app, azimm, elevv )
        end

        
        %% FIR FILTERS %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        function allocateFIRfilter(app)
            for k = 1:app.no_input*2
                % app.FIR{k} = dsp.FIRFilter();   
                app.FIR{k} =  dsp.FrequencyDomainFIRFilter('Method',...
                               'overlap-save',...
                              'PartitionForReducedLatency', true,...
                              'PartitionLength', round(app.N/2));
            end
        end


        %% Plot HpTFs ================================================
        function plot_HpTF(app, HpIR, H, freq)
            N_hpir = length(HpIR);
            HpTF = db(abs(fft(HpIR)));
            HpTF = HpTF(1:round(N_hpir/2));
            txt_legend = {'Headphone response', 'Implemented filter'};
            if nargin < 4 % imported from audio
                % make fft and plot freq response
                H = [];
                txt_legend(1) = [];
                freq = linspace(0, app.fs_opt_IR- app.fs_opt_IR/N_hpir, N_hpir);
                freq = freq(1:round(N_hpir/2));
            end
                
            
            %% plot -----------==========
            cla(app.UIAxes2);
            if nargin == 4
                semilogx(app.UIAxes2, freq, db(H)); 
            end
            hold(app.UIAxes2, 'on');
            xlabel(app.UIAxes2,'Frequency (Hz)');
            ylabel(app.UIAxes2,'Magnitude ')
            
            semilogx(app.UIAxes2,freq, HpTF)
            app.UIAxes2.XLim = [freq(2), freq(end)];
            
            l = legend(app.UIAxes2,txt_legend,...
                    'location', 'best', 'Box','off');
            l.TextColor = [.6 .6 .6];
            hold(app.UIAxes2, 'off');
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            movegui(app.UIFigure,'center')
            % Desabilitar iteratividade do plot
            disableDefaultInteractivity(app.UIAxes); 
            disableDefaultInteractivity(app.UIAxes2); 
            app.UIAxes.Toolbar.Visible = 'off'; 
            set(app.UIAxes, 'FontSize', 10)

            % Check if required toolboxes are installed
            required_licenses= {'image_toolbox',...
                            'matlab',...
                            'signal_blocks',...
                            'signal_toolbox'};
            [licenses_ok, missing] = license_check(required_licenses);
            if licenses_ok
                % load default files
                AudioWAVButtonValueChanged(app,app.startUP);
                HRTFSOFAButtonValueChanged(app,app.startUP); 
            else
                uialert(app.UIFigure,['Some missing toolboxes prevented the app from running correctly, please try again after installing: ',...
                             missing], 'License and registration, please!');
            end
            
            
        end

        % Value changed function: HRTFSOFAButton
        function HRTFSOFAButtonValueChanged(app, event)
            if app.startUP
                baseName = 'individuo_141.sofa';
               app.Obj_HRTF = SOFAload(baseName, 'nochecks');
               app.SOFApath.Value = baseName; 
               app.startUP = 0;
            else % LOAD SOFA
                [baseName, folder] = uigetfile([pwd, filesep, '*.sofa'], 'Load SOFA HRTF:');
                app.SOFApath.Value = baseName;  
                figure(app.UIFigure) %keep focus on app window
                app.Obj_HRTF = SOFAload(fullfile(folder, baseName), 'nochecks');
            end
            %%% Properties
            if length(app.Obj_HRTF.Data.Delay) ~= length(app.Obj_HRTF.SourcePosition)
                [~, app.Obj_HRTF] = SOFAgetITD(app.Obj_HRTF,'samples');% save ITD in Obj.Data.Delay
            end
            app.posi = app.Obj_HRTF.SourcePosition;
            app.samplingGrid = app.posi(:,1:2);
            % convert coordinates for ploting
            [app.posi(:,1), app.posi(:,2)] = sph2nav(-app.posi(:,1),app.posi(:,2));
            
            % save utils
            app.headradius = app.Obj_HRTF.ReceiverPosition(1, 2); % raio da cabeça
            if app.headradius >= 0.25
                app.headradius = app.headradius/10;
            end
            app.DistanceSlider.Limits(1) = app.headradius+0.1;
            app.DistValue = app.DistanceSlider.Value;
            app.DistanceSlider.Value = app.Obj_HRTF.SourcePosition(1,3);
            
            app.fs_SOFA = app.Obj_HRTF.Data.SamplingRate;
            app.N = size(app.Obj_HRTF.Data.IR,3);
            app.HRTFs = shiftdim(app.Obj_HRTF.Data.IR, 2);  
            
            app.dspHRIR_L = app.HRTFs(:,:,1).';
            app.dspHRIR_R = app.HRTFs(:,:,2).';
            
            % General flags
            if app.no_input >= 1 % Verifica se audio já foi inserido
                app.PlayButton.Enable = true;                   
                app.PauseButton.Enable = true;                
            end 
            app.ROI_flag = 1;
            delete(app.roi_listen);
            app.flag_airabs_eval = true;
            app.flag_DistChanged = 1;
            
            % Check air absoption
            if app.AirabsorptionCheckBox.Value                
                AirabsorptionCheckBoxValueChanged(app, 1);                
            end

            % Set plot properties
            color = [0.5, 0.47, 0.53];
            app.props = {'LineStyle','none','Marker','o','MarkerEdge',color,'MarkerSize',5, ...
                         'MarkerFaceColor', color};
            
         
        end

        % Value changed function: AudioWAVButton
        function AudioWAVButtonValueChanged(app, event)
            frameLength = app.BufferSize.Value; %make this a user defined option
            if app.startUP
                app.NameAudio = 'Voice Sabine Short_edited.wav'; 
                [aud, app.fs_Audio] = audioread(app.NameAudio);
%                 aud = aud./max(abs(aud(:)))*0.975;
                aud = aud*((10^(-8/20))/(max(abs(aud(:)))));
                app.Audio = dsp.SignalSource(aud, frameLength); 
            else
                [NameAud, Audfolder] = uigetfile([pwd, filesep, '*.wav'], 'Load Audio File:');
                app.NameAudio = NameAud; %nomeie fora da função, caso o usuario feche a janela sem selecionar arquivo
                app.Audiofolder = Audfolder;
                figure(app.UIFigure) %keep focus on the app window
                [aud, app.fs_Audio] = audioread(fullfile(app.Audiofolder, app.NameAudio));
%                 aud = aud./max(abs(aud(:)))*0.975;
                aud = aud*((10^(-8/20))/(max(abs(aud(:)))));
                app.Audio = dsp.SignalSource(aud, frameLength); 
            end           
            app.AUDIOpath.Value = app.NameAudio;   
            app.deviceWriter = audioDeviceWriter( ...
                                    "SampleRate", app.fs_Audio,...
                                    "BitDepth","16-bit integer"); 
            app.no_input = size(app.Audio(), 2);   
            app.ROI_flag = 1;
            app.conv_done = false;
            
        end

        % Value changed function: PlayButton
        function PlayButtonValueChanged(app, event)
            app.PlayButton.Value = 0;
            app.PlayButton.Enable = false;
            app.HRTFSOFAButton.Enable = false;
            app.AudioWAVButton.Enable = false;
            app.LoadHpIRwavButton.Enable = false;
            app.BinauralRenderButton.Enable = false;
            app.XButton.Enable = false;
            app.PauseButton.Value = 0;
            app.LoadfromDatabaseButton.Enable = false;
            allocateFIRfilter(app)
            
            %%% PLAY 
            % only allow auralize if Sample Rate matchs
            if app.fs_Audio ~= app.fs_SOFA 
                uialert(app.UIFigure, ['Sample rate: HRTFs are ', ...
                    num2str(app.fs_SOFA/1000) 'kHz, while the audio is ', ...
                    num2str(app.fs_Audio/1000) 'kHz'], 'Error');
                % use 'uiconfirm' instead and add a callback to resample
                % either the sofa or the audio
                app.BinauralRenderButton.Enable = true;
                app.PlayButton.Enable = true;
                app.PauseButton.Enable = true;
                app.HRTFSOFAButton.Enable = true;
                app.AudioWAVButton.Enable = true;
                app.LoadHpIRwavButton.Enable = true;   
                app.XButton.Enable = true;
            else  
                % Verificar se HeadTracker esta aberto ou foi fechado
                check_HT_status(app)
                if app.ROI_flag % caso precise redesenhar posicoes, desenhe
                    switch app.ViewType.Value 
                        case 'Top'
                            draw_top_view(app)                    
                        otherwise                    
                            draw_front_view(app)
                    end
                end
                
                %%%% Fazer convolução HpTF 
                if app.flag_opt_IR && ~app.conv_done
                   optional_IR_conv(app) 
                end                                          
        
                % Play Obj_HRTFect setup
                release(app.Audio)
                release(app.deviceWriter)
                app.BufferSize.Value = 2^nextpow2(app.BufferSize.Value);
                app.Audio.SamplesPerFrame = app.BufferSize.Value;
                setup(app.deviceWriter,zeros(app.BufferSize.Value, 2))
                out_temp_L = zeros(app.BufferSize.Value,app.no_input);
                out_temp_R = zeros(app.BufferSize.Value,app.no_input);
                yaw = 0;
                pitch = 0;
                pos = app.posi(:,1:2);
                idx_changer = inf;
                release(app.Audio)                         
                release(app.deviceWriter)  
        
        %% ############## PLAY AND PROCESS AUDIO ##############################################################    
                
                while ~isDone(app.Audio)   
                    drawnow('limitrate')   % permite a movimentação do cursor
%                     pause(0)
                    % Check pause button action
                    if app.PauseButton.Value      
                        break
                    end                    
                    audioIn = app.Audio();                     
                    % Apply HRTFs
                    if app.flag_HeadTracker
                        py_output = app.udpr();
                        if ~isempty(py_output)                          
                            data = str2double(split(convertCharsToStrings(char(py_output)), ','));
                            yaw = data(1);
                            pitch = data(2);
                        end
                    end                    
                    for k = 1:app.no_input
                        name = app.ROI_list{k};                                         
                        if app.flag_HeadTracker  % Calibrate for Head Tracker position
                            idx_posL = dsearchn(pos, ...
                                               (pos(app.idx_FIR.(name)(1),:) - [yaw, pitch])); 
                            idx_posR = dsearchn(pos, ...
                                               (pos(app.idx_FIR.(name)(2),:) - [yaw, pitch]));
                        else
                            idx_posL = app.idx_FIR.(name)(1);
                            idx_posR = app.idx_FIR.(name)(2);
                        end   
        
                        % HRTFs     
                        if idx_changer ~= idx_posL % if position is the same no need to update numerator
                            app.FIR{k}.Numerator = app.dspHRIR_L(idx_posL,:);
                            app.FIR{k+app.no_input}.Numerator = app.dspHRIR_R(idx_posR,:);
                        end
                        out_temp_L(:,k) = app.FIR{k}(audioIn(:,k)); % Left
                        if ~app.flag_opt_IR 
                            out_temp_R(:,k) = app.FIR{k+app.no_input}(audioIn(:,k)); % Right  
                        else % Caso IR opcional inserida                                     
                            out_temp_R(:,k) = app.FIR{k+app.no_input}(audioIn(:,k+app.no_input)); % Right
                            % Acima: o termo (+app.no_input) foi adicionado
                            % pq cada canal do audioIn foi convoluido com
                            % os dois canais da RI inserida
                        end   
        
                        % Apply HRTF eq for distance variation (if required)
                        method = app.DistancevariationmethodListBox.Value;
                        if strcmpi(method, 'HRTF equalization')
                            out_temp_L(:,k) = app.dist_FIR.(name){1}(out_temp_L(:,k));
                            out_temp_R(:,k) = app.dist_FIR.(name){2}(out_temp_R(:,k));    
                        elseif strcmpi(method, 'Inverse Law') 
                            out_temp_L(:,k) = out_temp_L(:,k).*app.DistNorm(k,1);
                            out_temp_R(:,k) = out_temp_R(:,k).*app.DistNorm(k,2);
                        end                        
                    end
                    idx_changer = idx_posL;
        
                    %% %% OUTPUT %%%%% 
                    y = [mean(out_temp_L,2), mean(out_temp_R,2)];
                    app.deviceWriter(y); 
                end
            end
            
            %%% after it's done
            release(app.Audio)                         
            release(app.deviceWriter)  
            app.PlayButton.Enable = true;
            app.HRTFSOFAButton.Enable = true;
            app.AudioWAVButton.Enable = true;
            app.LoadHpIRwavButton.Enable = true;
            app.BinauralRenderButton.Enable = true; 
            app.LoadfromDatabaseButton.Enable = true;
            if app.flag_opt_IR
               app.XButton.Enable = true;
            end          
            for k =1:app.no_input*2
                release(app.FIR{k});
            end
                       
        end

        % Value changed function: LoadHpIRwavButton
        function LoadHpIRwavButtonValueChanged(app, event)
            [IR_name, folder] = uigetfile([pwd, filesep, '*.wav'], 'Load Impulse Response:');
            figure(app.UIFigure) %keep focus on the app window
            [temp_ir, app.fs_opt_IR] = audioread(fullfile(folder, IR_name));
            % Suporte para no máximo dois canais
            if size(temp_ir, 2) > 2
                 uialert(app.UIFigure, 'Number of channels should not be higher than 2', 'Error');
            else
                app.opt_IR = (temp_ir./max(abs(temp_ir(:))))*0.975;
                if size(app.opt_IR, 2) < 2 % if mono make it dual channel                        
                    app.opt_IR(:,2) = app.opt_IR(:,1);                                                                                                                             
                end
                app.opt_IRpath.Value = IR_name;
                app.flag_opt_IR = 1;
                app.conv_done = false;
%                 app.ROI_flag = 1;                
                app.XButton.Enable = true; 
                % plot spectra
                plot_HpTF(app, app.opt_IR)
            end          
        end

        % Value changed function: BinauralRenderButton
        function BinauralRenderButtonValueChanged(app, event)
            release(app.Audio)                         
            release(app.deviceWriter)   
            app.BinauralRenderButton.Enable = false;
            app.PlayButton.Enable = false;
            app.PauseButton.Enable = false;
            app.HRTFSOFAButton.Enable = false;
            app.AudioWAVButton.Enable = false;
            app.LoadHpIRwavButton.Enable = false;
            app.XButton.Enable = false;
            
            wait = waitbar(0, 'Processing output...', 'Name','Rendering:');           
            if ~app.flag_opt_IR % Load audio again
                [aud, ~] = audioread(fullfile(app.Audiofolder, app.NameAudio));
            else
                optional_IR_conv(app) % make sure IR is convoluted
            end
            
            for k = 1:app.no_input    
                waitbar(k/app.no_input, wait)
                name = app.ROI_list{k};  
                            idx_posL = app.idx_FIR.(name)(1);
                            idx_posR = app.idx_FIR.(name)(2);
                % Convolução saída        
                if app.flag_opt_IR % Caso IR opcional inserida                   
                    nfft = length(app.convAudio(:,k)) + length(app.dspHRIR_L(idx_posL,:)) - 1;
                    out_render_L(:,k)=real(ifft(fft(app.convAudio(:,k), nfft).* ...
                                         fft(app.dspHRIR_L(idx_posL,:).', nfft), nfft)); % Left
                    out_render_R(:,k)=real(ifft(fft(app.convAudio(:,k+app.no_input), nfft).*...
                                         fft(app.dspHRIR_R(idx_posR,:).', nfft), nfft)); % Right
                else
                    nfft = length(aud(:,k)) + length(app.dspHRIR_L(idx_posL,:)) - 1;
                    out_render_L(:,k) = ifft(fft(aud(:,k),nfft).* fft(app.dspHRIR_L(idx_posL,:).',nfft),nfft); % Left
                    out_render_R(:,k) = ifft(fft(aud(:,k),nfft).* fft(app.dspHRIR_R(idx_posR,:).',nfft),nfft); % Right
                end%app.dspHRIR_R(idx_posR,:)
                waitbar((k/app.no_input + k/(app.no_input*2)), wait)
            end 
                      
            y = [mean(out_render_L,2) mean(out_render_R,2)];  
            y = y./max(abs(y(:)))*0.975;
            % Ask for save path and name    
            try
                [~, baseFileNameNoExt, ~] = fileparts([app.Audiofolder  app.NameAudio ]);
                [file,path,~] = uiputfile([baseFileNameNoExt, '_EAC.wav']);                
                audiowrite([path file], y, app.fs_Audio)
            catch
            end
            figure(app.UIFigure) %keep focus on the app window
            waitbar(1, wait); pause(1); close(wait)
            
            app.BinauralRenderButton.Enable = true;
            app.PlayButton.Enable = true;
            app.PauseButton.Enable = true;
            app.HRTFSOFAButton.Enable = true;
            app.AudioWAVButton.Enable = true;
            app.LoadHpIRwavButton.Enable = true;   
            app.XButton.Enable = true;
        end

        % Button pushed function: XButton
        function XButtonPushed(app, event)
            app.fs_opt_IR = [];
            app.opt_IR = [];
            app.opt_IRpath.Value = '';
            app.flag_opt_IR = 0;
            app.conv_done = false;
            % Remover convolução
            release(app.Audio)                         
            release(app.deviceWriter)
            [aud, ~] = audioread(fullfile(app.Audiofolder, app.NameAudio));
            app.Audio = dsp.SignalSource(aud,app.BufferSize.Value); 
            cla(app.UIAxes2);
        end

        % Value changing function: DistanceSlider
        function DistanceSliderValueChanging(app, event)
            app.DistValue = event.Value;
            app.flag_DistChanged = 1;            
            if ~app.ROI_flag
                get_parallax_pos(app)  % recalcular parallax
                
                for k=1:app.no_input 
                    app.radius.(app.ROI_list{k}) = app.DistValue;
                end               
            end
            DistancevariationmethodListBoxValueChanged(app,1)
            app.flag_DistChanged = 0;
        end

        % Value changed function: AirabsorptionCheckBox
        function AirabsorptionCheckBoxValueChanged(app, event)
        
            % Calcula absorção somente se for atualizado as HRTFs
            if app.flag_airabs_eval % atualizar valor da absorcao
                freq = linspace(0, app.fs_SOFA-app.fs_SOFA/app.N, app.N);
                app.air_abs = 0;
                for k = 1:length(freq)
                    [~, app.air_abs(k,1), ~, ~] = air_absorption(freq(k),...
                                                            app.airTemperature,...
                                                            app.airHumidity,...
                                                            app.airPressure);                    
                end
                % generate filter                   
                app.norm_freq = freq(1:app.N/2)./max(freq(1:app.N/2)); % frequency vector (half)             
                app.abs_dist_vec = app.headradius:0.2:5; % pre-initialized positions
                N_air_pos = length(app.abs_dist_vec);
                app.AirAbsFilter = cell(N_air_pos, 1);
                for k = 1:N_air_pos
                    alpha = 10.^(  -app.air_abs(1:app.N/2,1).*app.abs_dist_vec(k)  ./20);% linear magitude               
                    d = fdesign.arbmag('N,F,A',50, app.norm_freq,alpha); 
                    app.AirAbsFilter{k} = design(d,'freqsamp','SystemObject',true); 
                end
                app.flag_airabs_eval = false;
                
            end
            
            if app.AirabsorptionCheckBox.Value % se absorção do ar for ativada
                if ~app.ROI_flag % se tiver posições pra calcular no grid: calcule                    
                    if app.flag_HeadTracker % se HT ativado precisa atualizar o grid inteiro   
                        % confirma se HT realmente ativo (dentro do if pra evitar overhead caso ja nao esteja ligado mesmo)
                        check_HT_status(app) 
                        idx_air_abs = dsearchn(app.abs_dist_vec', app.DistValue);
                        air_filter = app.AirAbsFilter{idx_air_abs};
                        for ii=1:length(app.posi)                       
                            app.dspHRIR_L(ii,:) = air_filter(app.HRTFs(:,ii,1));
                            app.dspHRIR_R(ii,:) = air_filter(app.HRTFs(:,ii,2));
                        end
                    else % sem head tracker
                        DistancevariationmethodListBoxValueChanged(app,1) % lazy way 
                    end
                end
            else % se desativada: reset it all 
                app.dspHRIR_L = app.HRTFs(:,:,1).';
                app.dspHRIR_R = app.HRTFs(:,:,2).';
            end
        end

        % Button pushed function: CAMHeadTrackerButton
        function CAMHeadTrackerButtonPushed(app, event)
            try
                open(which('Headtracker.exe', 'first'));               
            catch
                uialert(app.UIFigure, 'Não foi possivel abrir ou encontrar HeadTracker.exe', 'Error');
            end         
            check_HT_status(app)
        end

        % Value changed function: airTemperature
        function airTemperatureValueChanged(app, event)
            value = app.airTemperature.Value;
            if app.AirabsorptionCheckBox.Value
                AirabsorptionCheckBoxValueChanged(app, value);
            end
        end

        % Value changed function: airHumidity
        function airHumidityValueChanged(app, event)
            value = app.airHumidity.Value;
            if app.AirabsorptionCheckBox.Value
                app.flag_airabs_eval = true;
                AirabsorptionCheckBoxValueChanged(app, value);
            end
        end

        % Value changed function: airPressure
        function airPressureValueChanged(app, event)
            value = app.airPressure.Value;
            if app.AirabsorptionCheckBox.Value
                app.flag_airabs_eval = true;
                AirabsorptionCheckBoxValueChanged(app, value);
            end
        end

        % Value changed function: PauseButton
        function PauseButtonValueChanged(app, event)
            %%% after it's done
            release(app.Audio);                         
            release(app.deviceWriter);
            app.PlayButton.Value = 1;
            app.PlayButton.Enable = true;
            app.HRTFSOFAButton.Enable = true;
            app.AudioWAVButton.Enable = true;
            app.LoadHpIRwavButton.Enable = true;
            app.BinauralRenderButton.Enable = true; 
            if app.flag_opt_IR
               app.XButton.Enable = true;
            end          
            for k =1:app.no_input*2
                release(app.FIR{k});
            end
        end

        % Value changed function: DistancevariationmethodListBox
        function DistancevariationmethodListBoxValueChanged(app, event)
            if ~app.ROI_flag
                for k=1:app.no_input
                    pos = app.ROI.(app.ROI_list{k}).Position;
                    if strcmpi(app.ViewType.Value, 'Top')
                        app.flag_DistChanged = true;
                        [azim, ~, ~]=cart2sph(pos(1), pos(2), 0);                    
                        azi = rad2deg(azim);
                        ele = app.elevation(k);                        
                    else
                        azi = pos(1);
                        ele = pos(2);
                    end
                    
                    HRIR_filter_design(app, azi, ele,...
                                      app.ROI.(app.ROI_list{k}).Tag)
                end               
            end
            
        end

        % Value changed function: ViewType
        function ViewTypeValueChanged(app, event)
            value = app.ViewType.Value; 
            switch value
                case 'Top'
                    app.DistanceSlider.Enable = false;
                otherwise
                    app.DistanceSlider.Enable = true;
            end
            if ~app.ROI_flag
                delete(app.roi_listen);               
                app.ROI_flag = 1;
                for k=1:app.no_input  
                    name = app.ROI_list{k};
                    x_coord = app.ROI.(name).Position(1);
                    y_coord = app.ROI.(name).Position(2);
                    
                    % top 2 frontal
                    if strcmpi(value, 'Frontal') 
                         [azimm(k),~,~]=cart2sph(x_coord, y_coord, app.radius.(name));                    
                         azimm(k) = rad2deg(azimm(k));    
                    % frontal 2 top     
                    else             
                        azimm(k) = x_coord;
                        app.elevation(k) = y_coord;
                        app.radius.(name) = app.DistValue;
                    end                    
                    delete(app.ROI.(name))
                end               
                
                switch value
                    case 'Top'
                        draw_top_view(app, azimm, app.elevation)                        
                    otherwise
                        if isempty(app.elevation)
                            app.elevation = zeros(1,app.no_input);
                        end
                        draw_front_view(app, azimm, app.elevation)                       
                end
                % Lazy shortcut to recalculate the actual positions
                DistancevariationmethodListBoxValueChanged(app)
            end
        end

        % Value changed function: LoadfromDatabaseButton
        function LoadfromDatabaseButtonValueChanged(app, event)
            % get resources intalation path
            rootSettings = matlab.internal.getSettingsRoot;
            addonFolder  = rootSettings.matlab.addons.InstallationFolder.ActiveValue;
            executableFolder = fullfile(addonFolder,'Apps','Auraliza');
            dataset_path = [executableFolder filesep '1 - Work' filesep...
                            'Auralization_Engine' filesep 'src' filesep...
                            'Curvas de fones'];
            % ask user input  
            [Name, Folder] = uigetfile([dataset_path,...
                                         filesep, '*.txt'],...
                           'Pick the headphone you want to correct:');            
            figure(app.UIFigure) %keep focus on the app window
            [HpIR, H, freq] = load_HpTF(fullfile(Folder, Name), app.fs_SOFA);
            % Atualizar vetores para convolução 
            app.opt_IR = (HpIR./max(abs(HpIR(:))))*0.975;
            if size(app.opt_IR, 2) < 2 % if mono make it dual channel                        
                app.opt_IR(:,2) = app.opt_IR(:,1);                                                                                                                             
            end
            app.fs_opt_IR = app.fs_SOFA;
            app.opt_IRpath.Value = Name;
            app.flag_opt_IR = 1;
            app.conv_done = false;
            app.XButton.Enable = true;          
            % Atualizar plot
            plot_HpTF(app, HpIR, H, freq)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.MainTab = uitab(app.TabGroup);
            app.MainTab.Title = 'Main';
            app.UIAxes = uiaxes(app.MainTab);
            app.UIAxes.XLim = [-180 180];
            app.UIAxes.YLim = [-90 90];
            app.UIAxes.XTick = [-180 -160 -140 -120 -100 -80 -60 -40 -20 0 20 40 60 80 100 120 140 160 180];
            app.UIAxes.XTickLabel = {'-180'; '-160'; '-140'; '-120'; '-100'; '-80'; '-60'; '-40'; '-20'; '0'; '20'; '40'; '60'; '80'; '100'; '120'; '140'; '160'; '180'};
            app.UIAxes.YTick = [-90 -60 -30 0 30 60 90];
            app.UIAxes.YTickLabel = {'-90'; '-60'; '-30'; '0'; '30'; '60'; '90'};
            app.InputsPanel = uipanel(app.MainTab);
            app.InputsPanel.Title = 'Inputs';
            app.SOFApath = uieditfield(app.InputsPanel, 'text');
            app.SOFApath.Editable = 'off';
            app.AUDIOpath = uieditfield(app.InputsPanel, 'text');
            app.AUDIOpath.Editable = 'off';
            app.HRTFSOFAButton = uibutton(app.InputsPanel, 'state');
            app.HRTFSOFAButton.ValueChangedFcn = createCallbackFcn(app, @HRTFSOFAButtonValueChanged, true);
            app.HRTFSOFAButton.Text = 'HRTF (.SOFA)';
            app.AudioWAVButton = uibutton(app.InputsPanel, 'state');
            app.AudioWAVButton.ValueChangedFcn = createCallbackFcn(app, @AudioWAVButtonValueChanged, true);
            app.AudioWAVButton.Text = 'Audio (.WAV)';
            app.AditionalparametersPanel = uipanel(app.MainTab);
            app.AditionalparametersPanel.Title = 'Aditional parameters';
            app.BufferSizeLabel = uilabel(app.AditionalparametersPanel);
            app.BufferSizeLabel.Text = 'Buffer Size';
            app.BufferSize = uieditfield(app.AditionalparametersPanel, 'numeric');
            app.BufferSize.Value = 512;
            app.InterpolationDropDownLabel = uilabel(app.AditionalparametersPanel);
            app.InterpolationDropDownLabel.Text = 'Interpolation:';
            app.InterpolationDropDown = uidropdown(app.AditionalparametersPanel);
            app.InterpolationDropDown.Items = {'Nearest', 'VBAP', ''};
            app.InterpolationDropDown.Value = 'Nearest';
            app.DIstancemLabel = uilabel(app.MainTab);
            app.DIstancemLabel.Text = {'DIstance'; '(m)'};
            app.DistanceSlider = uislider(app.MainTab);
            app.DistanceSlider.Limits = [0.2 4];
            app.DistanceSlider.MajorTicks = [0.25 1 1.75 2.5 3.25 4];
            app.DistanceSlider.ValueChangingFcn = createCallbackFcn(app, @DistanceSliderValueChanging, true);
            app.DistanceSlider.MinorTicks = [];
            app.DistanceSlider.Value = 2.5;
            app.AirabsorptionCheckBox = uicheckbox(app.MainTab);
            app.AirabsorptionCheckBox.ValueChangedFcn = createCallbackFcn(app, @AirabsorptionCheckBoxValueChanged, true);
            app.AirabsorptionCheckBox.Text = 'Air absorption';
            app.BinauralRenderButton = uibutton(app.MainTab, 'state');
            app.BinauralRenderButton.ValueChangedFcn = createCallbackFcn(app, @BinauralRenderButtonValueChanged, true);
            app.BinauralRenderButton.Enable = 'off';
            app.BinauralRenderButton.Text = 'Binaural Render';
            app.PlayButton = uibutton(app.MainTab, 'state');
            app.PlayButton.ValueChangedFcn = createCallbackFcn(app, @PlayButtonValueChanged, true);
            app.PlayButton.Enable = 'off';
            app.PlayButton.Icon = 'playbuttom.png';
            app.PlayButton.Text = {''; ''};
            app.PauseButton = uibutton(app.MainTab, 'state');
            app.PauseButton.ValueChangedFcn = createCallbackFcn(app, @PauseButtonValueChanged, true);
            app.PauseButton.Enable = 'off';
            app.PauseButton.Icon = 'stopbuttom.png';
            app.PauseButton.Text = '';
            app.CAMHeadTrackerButton = uibutton(app.MainTab, 'push');
            app.CAMHeadTrackerButton.ButtonPushedFcn = createCallbackFcn(app, @CAMHeadTrackerButtonPushed, true);
            app.CAMHeadTrackerButton.Text = {'CAM '; 'Head Tracker'};
            app.ViewType = uiswitch(app.MainTab, 'slider');
            app.ViewType.Items = {'Frontal', 'Top'};
            app.ViewType.ValueChangedFcn = createCallbackFcn(app, @ViewTypeValueChanged, true);
            app.ViewType.Value = 'Frontal';
            app.AdvancedTab = uitab(app.TabGroup);
            app.AdvancedTab.Title = 'Advanced';
            app.AirabsorptionpropertiesISO96131Panel = uipanel(app.AdvancedTab);
            app.AirabsorptionpropertiesISO96131Panel.Title = 'Air absorption properties (ISO 9613-1)';
            app.TemperatureCEditFieldLabel = uilabel(app.AirabsorptionpropertiesISO96131Panel);
            app.TemperatureCEditFieldLabel.Text = 'Temperature (C°)';
            app.airTemperature = uieditfield(app.AirabsorptionpropertiesISO96131Panel, 'numeric');
            app.airTemperature.ValueChangedFcn = createCallbackFcn(app, @airTemperatureValueChanged, true);
            app.airTemperature.Value = 20;
            app.HumidityLabel = uilabel(app.AirabsorptionpropertiesISO96131Panel);
            app.HumidityLabel.Text = 'Humidity (%) ';
            app.airHumidity = uieditfield(app.AirabsorptionpropertiesISO96131Panel, 'numeric');
            app.airHumidity.ValueChangedFcn = createCallbackFcn(app, @airHumidityValueChanged, true);
            app.airHumidity.Value = 80;
            app.AtmosphericpressureratioCurrentStandadEditFieldLabel = uilabel(app.AirabsorptionpropertiesISO96131Panel);
            app.AtmosphericpressureratioCurrentStandadEditFieldLabel.Text = {'Atmospheric pressure ratio'; '(Current / Standad) '};
            app.airPressure = uieditfield(app.AirabsorptionpropertiesISO96131Panel, 'numeric');
            app.airPressure.ValueChangedFcn = createCallbackFcn(app, @airPressureValueChanged, true);
            app.airPressure.Value = 1;
            app.DistancevariationmethodListBoxLabel = uilabel(app.AdvancedTab);
            app.DistancevariationmethodListBoxLabel.Text = 'Distance variation method';
            app.DistancevariationmethodListBox = uilistbox(app.AdvancedTab);
            app.DistancevariationmethodListBox.Items = {'Inverse Law', 'HRTF equalization'};
            app.DistancevariationmethodListBox.ValueChangedFcn = createCallbackFcn(app, @DistancevariationmethodListBoxValueChanged, true);
            app.DistancevariationmethodListBox.Value = 'Inverse Law';
            app.HeadphoneequalizationPanel = uipanel(app.AdvancedTab);
            app.HeadphoneequalizationPanel.Title = 'Headphone equalization';
            app.UIAxes2 = uiaxes(app.HeadphoneequalizationPanel);
            title(app.UIAxes2, {''; ''})
            xlabel(app.UIAxes2, 'Frequency (Hz)')
            ylabel(app.UIAxes2, 'Magnitude (dB)')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.XLim = [20 20000];
            app.UIAxes2.YLim = [-15 15];
            app.UIAxes2.XScale = 'log';
            app.opt_IRpath = uieditfield(app.HeadphoneequalizationPanel, 'text');
            app.opt_IRpath.Editable = 'off';
            app.LoadHpIRwavButton = uibutton(app.HeadphoneequalizationPanel, 'state');
            app.LoadHpIRwavButton.ValueChangedFcn = createCallbackFcn(app, @LoadHpIRwavButtonValueChanged, true);
            app.LoadHpIRwavButton.Text = {'Load HpIR '; '(.wav)'};
            app.XButton = uibutton(app.HeadphoneequalizationPanel, 'push');
            app.XButton.ButtonPushedFcn = createCallbackFcn(app, @XButtonPushed, true);
            app.XButton.Enable = 'off';
            app.XButton.Text = 'X';
            app.LoadfromDatabaseButton = uibutton(app.HeadphoneequalizationPanel, 'state');
            app.LoadfromDatabaseButton.ValueChangedFcn = createCallbackFcn(app, @LoadfromDatabaseButtonValueChanged, true);
            app.LoadfromDatabaseButton.Text = {'Load  from '; 'Database'};
            app.HelpTab = uitab(app.TabGroup);
            app.HelpTab.Title = 'Help';
            app.DaviRochaCarvalhoEngAcsticaUFSMLabel = uilabel(app.HelpTab);
            app.DaviRochaCarvalhoEngAcsticaUFSMLabel.Text = 'Davi Rocha Carvalho - Eng. Acústica UFSM.';
            app.davicarvalhoeacufsmbrLabel = uilabel(app.HelpTab);
            app.davicarvalhoeacufsmbrLabel.Text = 'davi.carvalho@eac.ufsm.br';
            app.ContactLabel = uilabel(app.HelpTab);
            app.ContactLabel.Text = 'Contact:';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Auraliza

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end